classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        Import                  matlab.ui.control.Button
        SmoothingSliderLabel    matlab.ui.control.Label
        SmoothingSlider         matlab.ui.control.Slider
        GenerateStlButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file % the Raw data that will uploaded into the app
        file_name string % Description
        smoothing_factor
    end
    
    methods (Access = private)
       function [tri] = DataToStl( Raw_data ,smoothing_factor )
%This functions turn a raw data into Stl file, ready for print

%inputs:
%Raw_data : one column data, in Csv ot txt format
%smoothing_factor

%outputs:
%3D plot of the solid object
%Stl file saved as GC×GC.stl

% Read the file
data =  Raw_data;

%turn the data table into array
data = table2array(data) ; 

%Extracting relative X and Y from Z matrix
[x, y] = meshgrid(1:size(data, 2), 1:size(data, 1));

%Lowering the number of data by decimation
decimationFactor = 2;
y_decimated = y(1:decimationFactor:end, :);
x_decimated = x(1:decimationFactor:end, :);
z_decimated = data(1:decimationFactor:end, :);

% Apply Gaussian smoothing
z_smoothed = imgaussfilt(z_decimated, smoothing_factor);

%Scaling
%This process have 2 parts, first defining an scale factor and then scaling
%scale factors for X and Y are defined in away that the final X and Y size would be 150 and 100
%the Z height is the mean of the X and Y
%X
x_ScaleFactor = 150 / max (x_decimated(:));
x_scaled = x_decimated * x_ScaleFactor;
%Y
y_ScaleFactor = 100 / max (y_decimated(:));
y_scaled = y_decimated * y_ScaleFactor;
%Z
z_ScaleFactor = ((100 + 150)/2) / max(z_smoothed(:)) ; %defining a scale factor to multiply all the Z and height to it (this transform the highest point of the graph to be mean of the X and Y size)%This make the printing easily 
z_scaled = z_smoothed * z_ScaleFactor ; %scale all the Z data 

% Flatten for triangulation
vertices = [x_scaled(:), y_scaled(:), z_scaled(:)]; %make vertices out of the data points(make a new matrix out of 3 matrix(each row is a 3D point
valid_indices = all(isfinite(vertices), 2); %remove unidentified values
vertices = vertices(valid_indices, :); %keeping the rows of the matrix with valid values

% generates a Delaunay triangulation using the X and Y coordinates from the vertices matrix
faces = delaunay(vertices(:, 1), vertices(:, 2));

% Create a solid volume from positive data
[F_solid, V_solid] = surf2solid(faces, vertices, 'elevation', -5); %2 is theheight of solid bottom(can be changed based on the data)

% Save STL file for positive peaks
tri = triangulation(F_solid, V_solid); %This command creates a triangulation object in MATLAB, which provides geometric and topological information about the mesh.


% figure;
% patch('Faces', F_solid, 'Vertices', V_solid,'FaceColor', 'cyan', 'EdgeColor', 'none');
% axis vis3d;
% view(3);
% camlight;
% lighting gouraud;
% xlabel('X-axis');
% ylabel('Y-axis');
% zlabel('Z-axis');
% title('Solid Volume');
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Import, UIAxes
        function ImportButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx' ; '*.xls' ; '*.txt' ; '*.csv'} , 'select a data file');
            if file == 0
                return;
            end 
            filepath = fullfile(path , file);
            app.file = readtable (filepath);
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.file_name = string(app.FileNameEditField.Value);
        end

        % Value changing function: SmoothingSlider
        function SmoothingSliderValueChanging(app, event)
            app.smoothing_factor = event.Value;
        end

        % Button pushed function: GenerateStlButton
        function GenerateStlButtonPushed(app, event)
              % Check if data has been loaded
    if isempty(app.file)
        uialert(app.UIFigure, 'No data loaded! Please upload a file.', 'Error');
        return;
    end
    
    % Check if file name is provided
    if isempty(app.file_name)
        uialert(app.UIFigure, 'Please enter a file name.', 'Error');
        return;
    end
    
    % If no smoothing factor is set, assign a default value
    if isempty(app.smoothing_factor)
        app.smoothing_factor = 1;
    end
    
    %use a previous function
    tri = DataToStl (app.file , app.smoothing_factor);
    
    % Construct the STL file name and save the file
    outFileName = strcat(app.file_name, ".stl");
    stlwrite(tri, outFileName);
    
    % Notify the user of success
    uialert(app.UIFigure, ['STL file saved as ', outFileName], 'Success');
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.Import = uibutton(app.UIFigure, 'push');
            app.Import.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.Import.Text = 'Import';
            app.SmoothingSliderLabel = uilabel(app.UIFigure);
            app.SmoothingSliderLabel.Text = 'Smoothing';
            app.SmoothingSlider = uislider(app.UIFigure);
            app.SmoothingSlider.Limits = [0 10];
            app.SmoothingSlider.ValueChangingFcn = createCallbackFcn(app, @SmoothingSliderValueChanging, true);
            app.GenerateStlButton = uibutton(app.UIFigure, 'push');
            app.GenerateStlButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStlButtonPushed, true);
            app.GenerateStlButton.Text = 'Generate Stl';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end